classdef OptionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        OKButton           matlab.ui.control.Button
        SaveOptionsButton  matlab.ui.control.Button
        LoadOptionsButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        options = 'none'; % 1 dimensional matrix to hold options. 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.CallingApp = caller;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            updateOptions(app.CallingApp, app.options)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.OptionsButton.Enable = "on";
            
            % Delete the dialog box 
            delete(app)   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadOptionsButton = uibutton(app.UIFigure, 'push');
            app.LoadOptionsButton.Text = 'Load Options';
            app.SaveOptionsButton = uibutton(app.UIFigure, 'push');
            app.SaveOptionsButton.Text = 'Save Options';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptionApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end